classdef MTracer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileTab                       matlab.ui.container.Tab
        LoadDataPanel                 matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        LoadApButton                  matlab.ui.control.Button
        LoadRezButton                 matlab.ui.control.Button
        ApLamp                        matlab.ui.control.Lamp
        LoadLfButton                  matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        LfLamp                        matlab.ui.control.Lamp
        RezLamp                       matlab.ui.control.Lamp
        lfbinEditFieldLabel           matlab.ui.control.Label
        LfEditField                   matlab.ui.control.EditField
        ApEditField                   matlab.ui.control.EditField
        apbinLabel                    matlab.ui.control.Label
        RezEditField                  matlab.ui.control.EditField
        rezmatEditFieldLabel          matlab.ui.control.Label
        TracingTab                    matlab.ui.container.Tab
        GridLayout3                   matlab.ui.container.GridLayout
        GridLayout8                   matlab.ui.container.GridLayout
        LoadButton                    matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        NameEditField                 matlab.ui.control.EditField
        NameEditFieldLabel            matlab.ui.control.Label
        TestButton                    matlab.ui.control.Button
        TestFunEditField              matlab.ui.control.EditField
        TestFunEditFieldLabel         matlab.ui.control.Label
        TrainButton                   matlab.ui.control.Button
        TrainFuncEditField            matlab.ui.control.EditField
        TrainFuncEditFieldLabel       matlab.ui.control.Label
        AutoTracingLabel              matlab.ui.control.Label
        GridLayout7                   matlab.ui.container.GridLayout
        DeleteTraceButton             matlab.ui.control.Button
        InspectTraceButton            matlab.ui.control.Button
        SaveTraceButton               matlab.ui.control.Button
        LoadTraceButton               matlab.ui.control.Button
        GridLayout6                   matlab.ui.container.GridLayout
        SaveMapDatatoWorkspaceButton  matlab.ui.control.Button
        MapDataButtonGroup            matlab.ui.container.ButtonGroup
        CorrectedButton               matlab.ui.control.RadioButton
        OriginalButton                matlab.ui.control.RadioButton
        MapsWindowButton              matlab.ui.control.Button
        GridLayout4                   matlab.ui.container.GridLayout
        TraceListLabel                matlab.ui.control.Label
        TraceListBox                  matlab.ui.control.ListBox
        HelpTab                       matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        PlotHelpLabel                 matlab.ui.control.Label
        NavigHelpLabel                matlab.ui.control.Label
        TraceHelpLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        vm MTracerVM; % View-model
    end
    
    methods
        function ind = SelectedTraceInd(app)
            value = app.TraceListBox.Value;
            ind = ismember(app.TraceListBox.Items, value);
            if ~any(ind)
                ind = NaN;
            else
                ind = find(ind);
            end
        end
        function SelectTrace(app, ind)
            if isnan(ind)
                app.TraceListBox.Value = {};
            else
                app.TraceListBox.Value = app.TraceListBox.Items(ind);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.TraceListBox.Items = {};
            app.vm = MTracerVM(app);
        end

        % Button pushed function: MapsWindowButton
        function MapsWindowButtonPushed(app, event)
            app.vm.InitializeMapAxes();
            app.vm.PlotLFP();
            app.vm.PlotSpikes();
            app.vm.PlotFocus();
            app.vm.PlotTraces();
        end

        % Button pushed function: LoadRezButton
        function LoadRezButtonPushed(app, event)
            app.LoadDataPanel.Enable = 'off';
            pause(0.2);
            app.RezLamp.Color = [0.93 0.69 0.13];
            app.vm.LoadKilosortRez(app.RezEditField.Value);
            if app.vm.hasSpikes
                app.RezEditField.Value = app.vm.rezFile;
                app.RezLamp.Color = [0.47 0.67 0.19];
                app.vm.PlotSpikes();
            else
                app.RezLamp.Color = [0 0 0];
                uialert(app.UIFigure, 'Unable to find or load rez.mat', 'Hmmm...', 'Icon', 'warning')
            end
            app.LoadDataPanel.Enable = 'on';
        end

        % Button pushed function: LoadLfButton
        function LoadLfButtonPushed(app, event)
            app.LoadDataPanel.Enable = 'off';
            pause(0.2);
            app.LfLamp.Color = [0.93 0.69 0.13];
            app.vm.LoadLFP(app.LfEditField.Value);
            if app.vm.hasLFP
                app.LfEditField.Value = app.vm.lfBinFile;
                app.LfLamp.Color = [0.47 0.67 0.19];
                app.vm.PlotLFP();
            else
                app.LfLamp.Color = [0 0 0];
                uialert(app.UIFigure, 'Unable to find or load lf.bin', 'Hmmm...', 'Icon', 'warning')
            end
            app.LoadDataPanel.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.vm);
            delete(app);
        end

        % Button pushed function: LoadTraceButton
        function LoadTraceButtonPushed(app, event)
            app.vm.LoadTraces();
        end

        % Button pushed function: SaveTraceButton
        function SaveTraceButtonPushed(app, event)
            app.vm.SaveTraces();
        end

        % Value changed function: TraceListBox
        function TraceListBoxValueChanged(app, event)
            app.vm.currentTrace = app.SelectedTraceInd();
            app.vm.PlotTraces();
        end

        % Button pushed function: DeleteTraceButton
        function DeleteTraceButtonPushed(app, event)
            app.vm.DeleteTrace(app.SelectedTraceInd());
        end

        % Button pushed function: InspectTraceButton
        function InspectTraceButtonPushed(app, event)
            ind = app.vm.currentTrace;
            if ~isnan(ind)
                for i = ind(:)'
                    app.vm.traces(i).PlotMotions();
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.LoadDataPanel = uipanel(app.FileTab);
            app.LoadDataPanel.Title = 'Load Data';
            app.GridLayout = uigridlayout(app.LoadDataPanel);
            app.rezmatEditFieldLabel = uilabel(app.GridLayout);
            app.rezmatEditFieldLabel.Text = 'rez.mat';
            app.RezEditField = uieditfield(app.GridLayout, 'text');
            app.apbinLabel = uilabel(app.GridLayout);
            app.apbinLabel.Text = 'ap.bin';
            app.ApEditField = uieditfield(app.GridLayout, 'text');
            app.LfEditField = uieditfield(app.GridLayout, 'text');
            app.lfbinEditFieldLabel = uilabel(app.GridLayout);
            app.lfbinEditFieldLabel.Text = 'lf.bin';
            app.RezLamp = uilamp(app.GridLayout);
            app.LfLamp = uilamp(app.GridLayout);
            app.ApLamp = uilamp(app.GridLayout);
            app.LoadRezButton = uibutton(app.GridLayout, 'push');
            app.LoadRezButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRezButtonPushed, true);
            app.LoadRezButton.Text = 'Load';
            app.LoadLfButton = uibutton(app.GridLayout, 'push');
            app.LoadLfButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLfButtonPushed, true);
            app.LoadLfButton.Text = 'Load';
            app.LoadApButton = uibutton(app.GridLayout, 'push');
            app.LoadApButton.Text = 'Load';
            app.TracingTab = uitab(app.TabGroup);
            app.TracingTab.Title = 'Tracing';
            app.GridLayout3 = uigridlayout(app.TracingTab);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.TraceListBox = uilistbox(app.GridLayout4);
            app.TraceListBox.Multiselect = 'on';
            app.TraceListBox.ValueChangedFcn = createCallbackFcn(app, @TraceListBoxValueChanged, true);
            app.TraceListBox.Value = {};
            app.TraceListLabel = uilabel(app.GridLayout4);
            app.TraceListLabel.Text = 'Trace List';
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.MapsWindowButton = uibutton(app.GridLayout6, 'push');
            app.MapsWindowButton.ButtonPushedFcn = createCallbackFcn(app, @MapsWindowButtonPushed, true);
            app.MapsWindowButton.Text = 'Maps Window';
            app.MapDataButtonGroup = uibuttongroup(app.GridLayout6);
            app.MapDataButtonGroup.Title = 'Map Data';
            app.OriginalButton = uiradiobutton(app.MapDataButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.CorrectedButton = uiradiobutton(app.MapDataButtonGroup);
            app.CorrectedButton.Text = 'Corrected';
            app.SaveMapDatatoWorkspaceButton = uibutton(app.GridLayout6, 'push');
            app.SaveMapDatatoWorkspaceButton.Text = {'Save Map Data'; 'to Workspace'};
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.LoadTraceButton = uibutton(app.GridLayout7, 'push');
            app.LoadTraceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTraceButtonPushed, true);
            app.LoadTraceButton.Text = 'Load';
            app.SaveTraceButton = uibutton(app.GridLayout7, 'push');
            app.SaveTraceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTraceButtonPushed, true);
            app.SaveTraceButton.Text = 'Save';
            app.InspectTraceButton = uibutton(app.GridLayout7, 'push');
            app.InspectTraceButton.ButtonPushedFcn = createCallbackFcn(app, @InspectTraceButtonPushed, true);
            app.InspectTraceButton.Text = 'Inspect';
            app.DeleteTraceButton = uibutton(app.GridLayout7, 'push');
            app.DeleteTraceButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteTraceButtonPushed, true);
            app.DeleteTraceButton.Text = 'Delete';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.AutoTracingLabel = uilabel(app.GridLayout8);
            app.AutoTracingLabel.Text = 'Auto Tracing';
            app.TrainFuncEditFieldLabel = uilabel(app.GridLayout8);
            app.TrainFuncEditFieldLabel.Text = 'Train Func';
            app.TrainFuncEditField = uieditfield(app.GridLayout8, 'text');
            app.TrainButton = uibutton(app.GridLayout8, 'push');
            app.TrainButton.Text = 'Train';
            app.TestFunEditFieldLabel = uilabel(app.GridLayout8);
            app.TestFunEditFieldLabel.Text = 'Test Fun';
            app.TestFunEditField = uieditfield(app.GridLayout8, 'text');
            app.TestButton = uibutton(app.GridLayout8, 'push');
            app.TestButton.Text = 'Test';
            app.NameEditFieldLabel = uilabel(app.GridLayout8);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout8, 'text');
            app.SaveButton = uibutton(app.GridLayout8, 'push');
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.GridLayout8, 'push');
            app.LoadButton.Text = 'Load';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.GridLayout2 = uigridlayout(app.HelpTab);
            app.TraceHelpLabel = uilabel(app.GridLayout2);
            app.TraceHelpLabel.Text = {'Plot Control'; ''; '* 1 : toggle focus indicator'; '* 2 : toggle spikes'; '* 3 : toggle LFP heatmap'; '* 4 : toggle ap timeseries'; '* 5 : toggle anchor points'; '* 6 : toggle interpolated traces'; '* . : increase spike marker size'; '* , : decrease spike marker size'};
            app.NavigHelpLabel = uilabel(app.GridLayout2);
            app.NavigHelpLabel.Text = {'Navigation Control'; ''; '* Click on map: set focus'; '* ] : zoom in time'; '* [ : zoom out time'; '* + : zoom in depth'; '* - : zoom out depth'; '* o : view the whole map'; '* c : center focus'; '* w/s/a/d : move up/down/left/right'; '* shift + w/s/a/d : move faster'; '* scroll : move left/right'};
            app.PlotHelpLabel = uilabel(app.GridLayout2);
            app.PlotHelpLabel.Text = {'Tracing Control'; ''; '* t : enter tracing mode'; '* esc : exit tracing mode'; '* Left click : add an anchor point'; '* Right click : undo selected point'; '* Ctrl + z : undo last point'; '* Ctrl + Shift + z : redo'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MTracer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end